classdef loadstartspeak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NotselectedLabel      matlab.ui.control.Label
        FileLabel             matlab.ui.control.Label
        GameimageLabel        matlab.ui.control.Label
        AudiodevicenameLabel  matlab.ui.control.Label
        PathLabel             matlab.ui.control.Label
        NogobackButton        matlab.ui.control.Button
        YesrunButton          matlab.ui.control.Button
        DoyouwanttoruntheexperimentwiththisconfigurationfileLabel  matlab.ui.control.Label
        ExperimentPanel       matlab.ui.container.Panel
        DelayLabel            matlab.ui.control.Label
        F0shiftLabel          matlab.ui.control.Label
        WashoutLabel          matlab.ui.control.Label
        HoldLabel             matlab.ui.control.Label
        RampLabel             matlab.ui.control.Label
        BaselineLabel         matlab.ui.control.Label
        F2shiftLabel          matlab.ui.control.Label
        F1shiftLabel          matlab.ui.control.Label
        PertUnitLabel         matlab.ui.control.Label
        PertTypeLabel         matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        SampleimageLabel      matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = private)
        
        function results = convertYesorNo(app, digit)
            if digit
                results = "  Yes";
            else
                results = "  No";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, path, file)
            app.CallingApp = mainapp;
            app.PathLabel.Text = append(path,file);
            app.CallingApp.ConfigParams = importdata([path file]);
            app.NotselectedLabel.Text = app.CallingApp.ConfigParams.Audapter.AudioDriverName;
            app.PertTypeLabel.Text = append(app.PertTypeLabel.Text,app.CallingApp.ConfigParams.Experiment.PertType);
            app.PertUnitLabel.Text = append(app.PertUnitLabel.Text,app.CallingApp.ConfigParams.Experiment.PertUnit);
            if strcmp(app.CallingApp.ConfigParams.Experiment.PertType, 'Formant shift')
                app.F1shiftLabel.Visible = 'on';
                app.F2shiftLabel.Visible = 'on';                         
                app.F0shiftLabel.Visible = 'off';
                app.DelayLabel.Visible = 'off';                
                app.F1shiftLabel.Text = append(app.F1shiftLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.F1Shift));
                app.F2shiftLabel.Text = append(app.F2shiftLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.F2Shift));
            elseif strcmp(app.CallingApp.ConfigParams.Experiment.PertType, 'Pitch shift')
                app.F1shiftLabel.Visible = 'off';
                app.F2shiftLabel.Visible = 'off';                         
                app.F0shiftLabel.Visible = 'on';
                app.DelayLabel.Visible = 'off';       
                app.F0shiftLabel.Text = append(app.F0shiftLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.F0Shift));
            elseif strcmp(app.CallingApp.ConfigParams.Experiment.PertType, 'Delay')
                app.F1shiftLabel.Visible = 'off';
                app.F2shiftLabel.Visible = 'off';                         
                app.F0shiftLabel.Visible = 'off';
                app.DelayLabel.Visible = 'on';
                app.DelayLabel.Text = append(app.DelayLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.Delay));
            end
            app.BaselineLabel.Text = append(app.BaselineLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.BaselineNumBlocks));
            app.RampLabel.Text = append(app.RampLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.RampNumBlocks));
            app.HoldLabel.Text = append(app.HoldLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.HoldNumBlocks));
            app.WashoutLabel.Text = append(app.WashoutLabel.Text,'  ',num2str(app.CallingApp.ConfigParams.Experiment.WashoutNumBlocks));
            app.GameimageLabel.Text = append(app.GameimageLabel.Text,'  ',app.CallingApp.ConfigParams.Game.PicPath);
            jpglist = dir(append(app.CallingApp.ConfigParams.Game.PicPath,'\*.jpg'));
            if isempty(jpglist);
                error("The selected directory has no pictures!")
            else
               app.Image.ImageSource = append(app.CallingApp.ConfigParams.Game.PicPath,'\',jpglist(1).name);
            end
            
        end

        % Button pushed function: NogobackButton
        function NogobackButtonPushed(app, event)
            reset(app.CallingApp);
            % Delete the dialog box  
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HTML3.Visible = 'on';
            delete(app)
        end

        % Button pushed function: YesrunButton
        function YesrunButtonPushed(app, event)
            %%add code here%%
            disp("start");
            runSPEAKboardgame(app.PathLabel.Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Image = uiimage(app.Panel);
            app.SampleimageLabel = uilabel(app.Panel);
            app.SampleimageLabel.Text = 'Sample image:';
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.PertTypeLabel = uilabel(app.ExperimentPanel);
            app.PertTypeLabel.Text = 'Pert Type:';
            app.PertUnitLabel = uilabel(app.ExperimentPanel);
            app.PertUnitLabel.Text = 'Pert Unit:';
            app.F1shiftLabel = uilabel(app.ExperimentPanel);
            app.F1shiftLabel.Text = 'F1 shift:';
            app.F2shiftLabel = uilabel(app.ExperimentPanel);
            app.F2shiftLabel.Text = 'F2 shift:';
            app.BaselineLabel = uilabel(app.ExperimentPanel);
            app.BaselineLabel.Text = 'Baseline:';
            app.RampLabel = uilabel(app.ExperimentPanel);
            app.RampLabel.Text = 'Ramp:';
            app.HoldLabel = uilabel(app.ExperimentPanel);
            app.HoldLabel.Text = 'Hold: ';
            app.WashoutLabel = uilabel(app.ExperimentPanel);
            app.WashoutLabel.Text = 'Washout:';
            app.F0shiftLabel = uilabel(app.ExperimentPanel);
            app.F0shiftLabel.Visible = 'off';
            app.F0shiftLabel.Text = 'F0 shift:';
            app.DelayLabel = uilabel(app.ExperimentPanel);
            app.DelayLabel.Visible = 'off';
            app.DelayLabel.Text = 'Delay:';
            app.DoyouwanttoruntheexperimentwiththisconfigurationfileLabel = uilabel(app.UIFigure);
            app.DoyouwanttoruntheexperimentwiththisconfigurationfileLabel.Text = 'Do you want to run the experiment with this configuration file?';
            app.YesrunButton = uibutton(app.UIFigure, 'push');
            app.YesrunButton.ButtonPushedFcn = createCallbackFcn(app, @YesrunButtonPushed, true);
            app.YesrunButton.Text = 'Yes (run)';
            app.NogobackButton = uibutton(app.UIFigure, 'push');
            app.NogobackButton.ButtonPushedFcn = createCallbackFcn(app, @NogobackButtonPushed, true);
            app.NogobackButton.Text = 'No (go back)';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path';
            app.AudiodevicenameLabel = uilabel(app.UIFigure);
            app.AudiodevicenameLabel.Text = 'Audio device name:';
            app.GameimageLabel = uilabel(app.UIFigure);
            app.GameimageLabel.Text = 'Game image:';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'File:';
            app.NotselectedLabel = uilabel(app.UIFigure);
            app.NotselectedLabel.Text = 'Not selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadstartspeak(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end