classdef createmenuspeak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PrevioustabButton               matlab.ui.control.Button
        SAVEtheconfigfileButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AudapterTab                     matlab.ui.container.Tab
        FindaudiodriversButton          matlab.ui.control.Button
        ChoosethedesiredaudiodriverListBox  matlab.ui.control.ListBox
        ChoosethedesiredaudiodriverListBoxLabel  matlab.ui.control.Label
        ExperimentTab                   matlab.ui.container.Tab
        F1ShiftEditFieldLabel           matlab.ui.control.Label
        F2ShiftEditField                matlab.ui.control.NumericEditField
        F2ShiftLabel                    matlab.ui.control.Label
        F1ShiftEditField                matlab.ui.control.NumericEditField
        PerturbationconfigurationPanel  matlab.ui.container.Panel
        F2UnitLabel                     matlab.ui.control.Label
        F1UnitLabel                     matlab.ui.control.Label
        F0UnitLabel                     matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        DelayShiftEditField             matlab.ui.control.NumericEditField
        Delay                           matlab.ui.control.Label
        F0ShiftEditField                matlab.ui.control.NumericEditField
        F0Shift                         matlab.ui.control.Label
        PerturbationtypeButtonGroup     matlab.ui.container.ButtonGroup
        DelayButton                     matlab.ui.control.ToggleButton
        PitchshiftButton                matlab.ui.control.ToggleButton
        FormantshiftButton              matlab.ui.control.ToggleButton
        PerturbationunitButtonGroup     matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.ToggleButton
        CentButton                      matlab.ui.control.ToggleButton
        MelButton_2                     matlab.ui.control.ToggleButton
        HzButton                        matlab.ui.control.ToggleButton
        NumberofblocksPanel             matlab.ui.container.Panel
        WashoutblocksSpinner            matlab.ui.control.Spinner
        WashoutblocksSpinnerLabel       matlab.ui.control.Label
        HoldblocksSpinner               matlab.ui.control.Spinner
        HoldblocksSpinnerLabel          matlab.ui.control.Label
        RampblocksSpinner               matlab.ui.control.Spinner
        RampblocksSpinnerLabel          matlab.ui.control.Label
        BaselineblocksSpinner           matlab.ui.control.Spinner
        BaselineblocksSpinnerLabel      matlab.ui.control.Label
        GameTab                         matlab.ui.container.Tab
        GamepicturesPanel               matlab.ui.container.Panel
        DirectorynotselectedLabel       matlab.ui.control.Label
        SelectthedirectorywithgamepicturesButton  matlab.ui.control.Button
        SamplePictureLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RunTab                          matlab.ui.container.Tab
        SAVEandNEXTButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = private)
        
        
        function updateprogressbar(app, wbar, i)
            currentProg = min(round((size(wbar,2)-2)*(i/10)),size(wbar,2)-2);
            app.FindaudiodriversButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
            app.FindaudiodriversButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
            app.FindaudiodriversButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
        end
        
        
        function results = checkYesorNo(app, textofbutton)
            if contains(textofbutton,'Yes')
                results = 1; 
            else
                results = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HTML3.Visible = 'on';
            delete(app)
            
        end

        % Button pushed function: FindaudiodriversButton
        function FindaudiodriversButtonPushed(app, event)
            % Change button name to "Processing"
            app.FindaudiodriversButton.Text = 'Searching...';
            app.FindaudiodriversButton.FontSize = 15;
            % Put text on top of icon
            app.FindaudiodriversButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.FindaudiodriversButton.BackgroundColor,15,1,600),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.FindaudiodriversButton.Icon = wbar;
            updateprogressbar(app, wbar, 1); % update waitbar
            clc;
            err = 0;
            devicenames = {};
            % Add Audapter to the path
            mcode_path = 'C:\speechres\audapter_matlab\mcode';
            commonmcode_path = 'C:\speechres\commonmcode';
            audapter_path = 'C:\speechres\audapter\Audapter-2.1\BIN\Release';
            
            if ~(exist(mcode_path) && exist(commonmcode_path) && exist(audapter_path))
                errordlg('Audapter not found under the correct path!');
                error('Audapter not found under the correct path!');
            end
            
            addpath(mcode_path); 
            addpath(commonmcode_path);
            addpath(audapter_path);
            
            SPEAKpath = which('SPEAK.mlapp');
            SPEAKSourcepath = fullfile(SPEAKpath(1:end-12), 'SPEAK-source\');
            
            diary off; %make sure no diary is taking in place (no command window recording)
            if exist(fullfile(SPEAKSourcepath, 'devinfospeak'))
                delete(fullfile(SPEAKSourcepath, 'devinfospeak')); %delete the default file because we want a clean slate
            end
            updateprogressbar(app, wbar, 2);
            diary(fullfile(SPEAKSourcepath, 'devinfospeak')); %log that command window to a file
            
            Audapter(0); %get the audio dev info from audapter
            updateprogressbar(app, wbar, 3);
            devinfotext= importdata(fullfile(SPEAKSourcepath, 'devinfospeak')); %load it in to cells
            timeramount = 1;
            updateprogressbar(app, wbar, 4);
            while isempty(devinfotext) && timeramount < 10
                pause(timeramount);
                devinfotext= importdata('speak-source/devinfospeak'); %load it in to cells
                timeramount = timeramount + 1;
                updateprogressbar(app, wbar, 6);
            end
                updateprogressbar(app, wbar, 6);

            if timeramount > 9
                err = 1;
            else
                updateprogressbar(app, wbar, 1);
                Index1 = find(contains(devinfotext,'User-selected'));  %find the Index start
                Index2 = length(devinfotext);
                NumDev = Index2 - Index1;
                diary off;
                updateprogressbar(app, wbar, 7);
                for devid = Index1+1:Index2 %to match the dev ID, we are starting from 0
                    parseind = strfind(devinfotext{devid},' : ');
                    devicenames{length(devicenames)+1} = devinfotext{devid}(parseind(1)+3:parseind(2)-9); % parsing the string so that only thedev name appears
                end
                updateprogressbar(app, wbar, 10);
            end
                            
            if err == 0
                app.ChoosethedesiredaudiodriverListBox.Items = devicenames;
                app.ChoosethedesiredaudiodriverListBox.Visible = 'on';
            else
                error('Audapter is struggling to find your audio devices');
            end
            % remove waitbar
            app.FindaudiodriversButton.Icon = '';
            % Change button name
            app.FindaudiodriversButton.Text = 'Find audio driver(s)';
            app.FindaudiodriversButton.FontSize = 25;
            app.ChoosethedesiredaudiodriverListBoxLabel.Visible = 'on';

        end

        % Button pushed function: SAVEandNEXTButton
        function SAVEandNEXTButtonPushed(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(1).Title)
                app.CallingApp.ConfigParams.Audapter.AudioDriverName =  app.ChoosethedesiredaudiodriverListBox.Value;
                if strcmp(app.CallingApp.ConfigParams.Audapter.AudioDriverName, 'Item 1')
                    warndlg('Audio driver not selected!');
                end
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            elseif strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(2).Title)
                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
            elseif strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(3).Title)
                app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                app.SAVEtheconfigfileButton.Visible = 'on';
                app.SAVEandNEXTButton.Text = 'SAVE and RUN SPEAK';
            elseif strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(4).Title)
                [file,path] = uiputfile('*.mat');
                ConfigParams = app.CallingApp.ConfigParams;
                save(append(path,file),'ConfigParams')
                %%%%%%%%%%%and run here%%%%%%%%%%%
            end
        end

        % Button pushed function: PrevioustabButton
        function PrevioustabButtonPushed(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(4).Title)
                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                app.SAVEandNEXTButton.Text = 'SAVE and NEXT';
            elseif strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(3).Title)
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            elseif strcmp(app.TabGroup.SelectedTab.Title,app.TabGroup.Children(2).Title)
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                app.PrevioustabButton.Visible = 'off';
            end
        end

        % Button pushed function: SAVEtheconfigfileButton
        function SAVEtheconfigfileButtonPushed(app, event)
            %save
            if strcmp(app.CallingApp.ConfigParams.Audapter.AudioDriverName, 'Item 1')
                    warndlg('Audio driver not selected!');
            end            
            [file,path] = uiputfile('*.mat');
            ConfigParams = app.CallingApp.ConfigParams;
            save(append(path,file),'ConfigParams')
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,app.TabGroup.Children(1).Title)
                app.PrevioustabButton.Visible = 'off';
                app.SAVEtheconfigfileButton.Visible = 'off';
                app.SAVEandNEXTButton.Text = 'SAVE and NEXT';
            elseif strcmp(selectedTab.Title,app.TabGroup.Children(2).Title)
                app.PrevioustabButton.Visible = 'on';
                app.SAVEtheconfigfileButton.Visible = 'off';
                app.SAVEandNEXTButton.Text = 'SAVE and NEXT';
            elseif strcmp(selectedTab.Title,app.TabGroup.Children(3).Title)
                app.PrevioustabButton.Visible = 'on';
                app.SAVEtheconfigfileButton.Visible = 'off';
                app.SAVEandNEXTButton.Text = 'SAVE and NEXT';
            elseif strcmp(selectedTab.Title,app.TabGroup.Children(4).Title)
                app.PrevioustabButton.Visible = 'on';
                app.SAVEtheconfigfileButton.Visible = 'on';
                app.SAVEandNEXTButton.Text = 'SAVE and RUN SPEAK';
            end
        end

        % Selection changed function: PerturbationunitButtonGroup
        function PerturbationunitButtonGroupSelectionChanged(app, event)
            selectedButton = app.PerturbationunitButtonGroup.SelectedObject;
            app.CallingApp.ConfigParams.Experiment.PertUnit = selectedButton.Text;
            
            if strcmp(app.CallingApp.ConfigParams.Experiment.PertType,'Pitch shift')
                if (strcmp(selectedButton.Text, 'Cent') || strcmp(selectedButton.Text, 'Mel'))
                    app.F0UnitLabel.Text = append(selectedButton.Text,'s');
                else
                    app.F0UnitLabel.Text = selectedButton.Text;
                end
            elseif strcmp(app.CallingApp.ConfigParams.Experiment.PertType,'Formant shift')
                if (strcmp(selectedButton.Text, 'Cent') || strcmp(selectedButton.Text, 'Mel'))
                    app.F1UnitLabel.Text = append(selectedButton.Text,'s');
                    app.F2UnitLabel.Text = append(selectedButton.Text,'s');
                else
                    app.F1UnitLabel.Text = selectedButton.Text;
                    app.F2UnitLabel.Text = selectedButton.Text;
                end
                
            end
        end

        % Value changed function: F1ShiftEditField
        function F1ShiftEditFieldValueChanged(app, event)
            value = app.F1ShiftEditField.Value;
            app.CallingApp.ConfigParams.Experiment.F1Shift = value;
        end

        % Value changed function: F2ShiftEditField
        function F2ShiftEditFieldValueChanged(app, event)
            value = app.F2ShiftEditField.Value;
            app.CallingApp.ConfigParams.Experiment.F2Shift = value;
        end

        % Value changed function: BaselineblocksSpinner
        function BaselineblocksSpinnerValueChanged(app, event)
            value = app.BaselineblocksSpinner.Value;
            app.CallingApp.ConfigParams.Experiment.BaselineNumBlocks = value;
        end

        % Value changed function: RampblocksSpinner
        function RampblocksSpinnerValueChanged(app, event)
            value = app.RampblocksSpinner.Value;
            app.CallingApp.ConfigParams.Experiment.RampNumBlocks = value;
        end

        % Value changed function: HoldblocksSpinner
        function HoldblocksSpinnerValueChanged(app, event)
            value = app.HoldblocksSpinner.Value;
            app.CallingApp.ConfigParams.Experiment.HoldNumBlocks = value;
        end

        % Value changed function: WashoutblocksSpinner
        function WashoutblocksSpinnerValueChanged(app, event)
            value = app.WashoutblocksSpinner.Value;
            app.CallingApp.ConfigParams.Experiment.WashoutNumBlocks = value;
        end

        % Button pushed function: 
        % SelectthedirectorywithgamepicturesButton
        function SelectthedirectorywithgamepicturesButtonPushed(app, event)
            path=uigetdir(append(pwd,'\gameimages\word_pics'),'Pick a Directory');
            app.CallingApp.ConfigParams.Game.PicPath = path;
            app.DirectorynotselectedLabel.Text = path;
            jpglist = dir(append(path,'\*.jpg'));
            if isempty(jpglist);
                error("The selected directory has no pictures!")
            else
               app.Image.ImageSource = append(path,'\',jpglist(1).name);
            end
            %%%%%%% need to code so that the GUI comes on the top after
            %%% uigetdir
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Selection changed function: PerturbationtypeButtonGroup
        function PerturbationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PerturbationtypeButtonGroup.SelectedObject;
            app.CallingApp.ConfigParams.Experiment.PertType = selectedButton.Text;
            if ~strcmp(app.CallingApp.ConfigParams.Experiment.PertUnit, 'Hz')
                if startsWith(selectedButton.Text,'Formant')                    
                    if (strcmp(app.CallingApp.ConfigParams.Experiment.PertUnit, 'Cent') || strcmp(app.CallingApp.ConfigParams.Experiment.PertUnit, 'Mel'))
                        app.F1UnitLabel.Text = append(app.CallingApp.ConfigParams.Experiment.PertUnit,'s');
                        app.F2UnitLabel.Text = append(app.CallingApp.ConfigParams.Experiment.PertUnit,'s');
                    else
                        app.F1UnitLabel.Text = app.CallingApp.ConfigParams.Experiment.PertUnit;
                        app.F2UnitLabel.Text = app.CallingApp.ConfigParams.Experiment.PertUnit;
                    end                    
                elseif startsWith(selectedButton.Text,'Pitch')
                    if strcmp(app.CallingApp.ConfigParams.Experiment.PertUnit, 'Mel')
                        app.F0UnitLabel.Text = 'Cents';
                    elseif strcmp(app.CallingApp.ConfigParams.Experiment.PertUnit, 'Cent')
                        app.F0UnitLabel.Text = append(app.CallingApp.ConfigParams.Experiment.PertUnit, 's');
                    else
                        app.F0UnitLabel.Text = app.CallingApp.ConfigParams.Experiment.PertUnit;
                    end
                    %app.F0UnitLabel.Text = app.CallingApp.ConfigParams.Experiment.PertUnit;
                elseif startsWith(selectedButton.Text,'Delay')
                    app.msLabel.Text = 'ms';
                end
            end
            if startsWith(selectedButton.Text,'Formant')
                app.F1UnitLabel.Visible = 'on';
                app.F1ShiftEditField.Visible ='on';
                app.F1ShiftEditFieldLabel.Visible = 'on';
                app.F2UnitLabel.Visible = 'on';
                app.F2ShiftEditField.Visible ='on';  
                app.F2ShiftLabel.Visible = 'on';
                app.F0Shift.Visible = 'off';
                app.F0UnitLabel.Visible = 'off';
                app.F0ShiftEditField.Visible = 'off';
                app.Delay.Visible = 'off';
                app.msLabel.Visible = 'off'; 
                app.DelayShiftEditField.Visible = 'off';
                
                app.PerturbationunitButtonGroup.Enable = 'on';
                
                app.PerturbationunitButtonGroup.Buttons(1).Enable = 'on';
                app.PerturbationunitButtonGroup.Buttons(2).Enable = 'on';
                app.PerturbationunitButtonGroup.Buttons(3).Enable = 'on';
                app.PerturbationunitButtonGroup.Buttons(4).Enable = 'on';
                
            elseif startsWith(selectedButton.Text, 'Pitch')
                app.F0UnitLabel.Visible = 'on';
                app.F0ShiftEditField.Visible = 'on';
                app.F0Shift.Visible = 'on';            
                app.F1UnitLabel.Visible = 'off';
                app.F1ShiftEditField.Visible ='off';
                app.F1ShiftEditFieldLabel.Visible = 'off';
                app.F2UnitLabel.Visible = 'off';
                app.F2ShiftEditField.Visible ='off';
                app.F2ShiftLabel.Visible = 'off';
                app.Delay.Visible = 'off';
                app.msLabel.Visible = 'off'; 
                app.DelayShiftEditField.Visible = 'off';
                
                app.PerturbationunitButtonGroup.Enable = 'on';
                app.PerturbationunitButtonGroup.Buttons(1).Enable = 'off';
                app.PerturbationunitButtonGroup.Buttons(2).Enable = 'off';
                app.PerturbationunitButtonGroup.Buttons(3).Enable = 'on';
                app.PerturbationunitButtonGroup.Buttons(4).Enable = 'on';
                
            elseif startsWith(selectedButton.Text, 'Delay')
                app.F1UnitLabel.Visible = 'off';
                app.F1ShiftEditField.Visible ='off';
                app.F1ShiftEditFieldLabel.Visible = 'off';
                app.F2UnitLabel.Visible = 'off';
                app.F2ShiftEditField.Visible ='off';
                app.F2ShiftLabel.Visible = 'off';
                app.F0Shift.Visible = 'off';
                app.F0UnitLabel.Visible = 'off';
                app.F0ShiftEditField.Visible = 'off';
                app.Delay.Visible = 'on';
                app.msLabel.Visible = 'on'; 
                app.DelayShiftEditField.Visible = 'on'; 
                
                app.PerturbationunitButtonGroup.Enable = 'off';
                
                app.CallingApp.ConfigParams.Experiment.PertUnit = 'ms';
               
            end
        end

        % Value changed function: DelayShiftEditField
        function DelayShiftEditFieldValueChanged(app, event)
            value = app.DelayShiftEditField.Value;
            app.CallingApp.ConfigParams.Experiment.Delay = value;
        end

        % Value changed function: F0ShiftEditField
        function F0ShiftEditFieldValueChanged(app, event)
            value = app.F0ShiftEditField.Value;
            app.CallingApp.ConfigParams.Experiment.F0Shift = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SAVEandNEXTButton = uibutton(app.UIFigure, 'push');
            app.SAVEandNEXTButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEandNEXTButtonPushed, true);
            app.SAVEandNEXTButton.Text = {'SAVE and NEXT'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AudapterTab = uitab(app.TabGroup);
            app.AudapterTab.Title = 'Audapter';
            app.ChoosethedesiredaudiodriverListBoxLabel = uilabel(app.AudapterTab);
            app.ChoosethedesiredaudiodriverListBoxLabel.Visible = 'off';
            app.ChoosethedesiredaudiodriverListBoxLabel.Text = 'Choose the desired audio driver';
            app.ChoosethedesiredaudiodriverListBox = uilistbox(app.AudapterTab);
            app.ChoosethedesiredaudiodriverListBox.Visible = 'off';
            app.FindaudiodriversButton = uibutton(app.AudapterTab, 'push');
            app.FindaudiodriversButton.ButtonPushedFcn = createCallbackFcn(app, @FindaudiodriversButtonPushed, true);
            app.FindaudiodriversButton.Text = {'Find audio driver(s)'; ''};
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.NumberofblocksPanel = uipanel(app.ExperimentTab);
            app.NumberofblocksPanel.Title = 'Number of blocks';
            app.BaselineblocksSpinnerLabel = uilabel(app.NumberofblocksPanel);
            app.BaselineblocksSpinnerLabel.Text = 'Baseline blocks';
            app.BaselineblocksSpinner = uispinner(app.NumberofblocksPanel);
            app.BaselineblocksSpinner.Limits = [0 Inf];
            app.BaselineblocksSpinner.ValueChangedFcn = createCallbackFcn(app, @BaselineblocksSpinnerValueChanged, true);
            app.RampblocksSpinnerLabel = uilabel(app.NumberofblocksPanel);
            app.RampblocksSpinnerLabel.Text = 'Ramp blocks';
            app.RampblocksSpinner = uispinner(app.NumberofblocksPanel);
            app.RampblocksSpinner.Limits = [0 Inf];
            app.RampblocksSpinner.ValueChangedFcn = createCallbackFcn(app, @RampblocksSpinnerValueChanged, true);
            app.HoldblocksSpinnerLabel = uilabel(app.NumberofblocksPanel);
            app.HoldblocksSpinnerLabel.Text = 'Hold blocks';
            app.HoldblocksSpinner = uispinner(app.NumberofblocksPanel);
            app.HoldblocksSpinner.Limits = [0 Inf];
            app.HoldblocksSpinner.ValueChangedFcn = createCallbackFcn(app, @HoldblocksSpinnerValueChanged, true);
            app.WashoutblocksSpinnerLabel = uilabel(app.NumberofblocksPanel);
            app.WashoutblocksSpinnerLabel.Text = 'Washout blocks';
            app.WashoutblocksSpinner = uispinner(app.NumberofblocksPanel);
            app.WashoutblocksSpinner.Limits = [0 Inf];
            app.WashoutblocksSpinner.ValueChangedFcn = createCallbackFcn(app, @WashoutblocksSpinnerValueChanged, true);
            app.PerturbationconfigurationPanel = uipanel(app.ExperimentTab);
            app.PerturbationconfigurationPanel.Title = 'Perturbation configuration';
            app.PerturbationunitButtonGroup = uibuttongroup(app.PerturbationconfigurationPanel);
            app.PerturbationunitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PerturbationunitButtonGroupSelectionChanged, true);
            app.PerturbationunitButtonGroup.Title = 'Perturbation unit';
            app.HzButton = uitogglebutton(app.PerturbationunitButtonGroup);
            app.HzButton.Text = 'Hz';
            app.HzButton.Value = true;
            app.MelButton_2 = uitogglebutton(app.PerturbationunitButtonGroup);
            app.MelButton_2.Text = 'Mel';
            app.CentButton = uitogglebutton(app.PerturbationunitButtonGroup);
            app.CentButton.Text = 'Cent';
            app.Button = uitogglebutton(app.PerturbationunitButtonGroup);
            app.Button.Text = '%';
            app.PerturbationtypeButtonGroup = uibuttongroup(app.PerturbationconfigurationPanel);
            app.PerturbationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PerturbationtypeButtonGroupSelectionChanged, true);
            app.PerturbationtypeButtonGroup.Title = 'Perturbation type';
            app.FormantshiftButton = uitogglebutton(app.PerturbationtypeButtonGroup);
            app.FormantshiftButton.Text = 'Formant shift';
            app.FormantshiftButton.Value = true;
            app.PitchshiftButton = uitogglebutton(app.PerturbationtypeButtonGroup);
            app.PitchshiftButton.Text = 'Pitch shift';
            app.DelayButton = uitogglebutton(app.PerturbationtypeButtonGroup);
            app.DelayButton.Text = 'Delay';
            app.F0Shift = uilabel(app.PerturbationconfigurationPanel);
            app.F0Shift.Visible = 'off';
            app.F0Shift.Text = 'F0 Shift';
            app.F0ShiftEditField = uieditfield(app.PerturbationconfigurationPanel, 'numeric');
            app.F0ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @F0ShiftEditFieldValueChanged, true);
            app.F0ShiftEditField.Visible = 'off';
            app.Delay = uilabel(app.PerturbationconfigurationPanel);
            app.Delay.Visible = 'off';
            app.Delay.Text = 'Delay';
            app.DelayShiftEditField = uieditfield(app.PerturbationconfigurationPanel, 'numeric');
            app.DelayShiftEditField.ValueChangedFcn = createCallbackFcn(app, @DelayShiftEditFieldValueChanged, true);
            app.DelayShiftEditField.Visible = 'off';
            app.msLabel = uilabel(app.PerturbationconfigurationPanel);
            app.msLabel.Visible = 'off';
            app.msLabel.Text = 'ms';
            app.F0UnitLabel = uilabel(app.PerturbationconfigurationPanel);
            app.F0UnitLabel.Visible = 'off';
            app.F0UnitLabel.Text = '';
            app.F1UnitLabel = uilabel(app.PerturbationconfigurationPanel);
            app.F1UnitLabel.Text = {'Hz'; ''};
            app.F2UnitLabel = uilabel(app.PerturbationconfigurationPanel);
            app.F2UnitLabel.Text = {'Hz'; ''};
            app.F1ShiftEditField = uieditfield(app.ExperimentTab, 'numeric');
            app.F1ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @F1ShiftEditFieldValueChanged, true);
            app.F2ShiftLabel = uilabel(app.ExperimentTab);
            app.F2ShiftLabel.Text = 'F2 Shift';
            app.F2ShiftEditField = uieditfield(app.ExperimentTab, 'numeric');
            app.F2ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @F2ShiftEditFieldValueChanged, true);
            app.F1ShiftEditFieldLabel = uilabel(app.ExperimentTab);
            app.F1ShiftEditFieldLabel.Text = 'F1 Shift';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.GamepicturesPanel = uipanel(app.GameTab);
            app.GamepicturesPanel.Title = 'Game pictures';
            app.Image = uiimage(app.GamepicturesPanel);
            app.SamplePictureLabel = uilabel(app.GamepicturesPanel);
            app.SamplePictureLabel.Text = 'Sample Picture';
            app.SelectthedirectorywithgamepicturesButton = uibutton(app.GamepicturesPanel, 'push');
            app.SelectthedirectorywithgamepicturesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectthedirectorywithgamepicturesButtonPushed, true);
            app.SelectthedirectorywithgamepicturesButton.Text = 'Select the directory with game pictures';
            app.DirectorynotselectedLabel = uilabel(app.GamepicturesPanel);
            app.DirectorynotselectedLabel.Text = 'Directory not selected';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.SAVEtheconfigfileButton = uibutton(app.UIFigure, 'push');
            app.SAVEtheconfigfileButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEtheconfigfileButtonPushed, true);
            app.SAVEtheconfigfileButton.Visible = 'off';
            app.SAVEtheconfigfileButton.Text = {'SAVE the config file'; ''};
            app.PrevioustabButton = uibutton(app.UIFigure, 'push');
            app.PrevioustabButton.ButtonPushedFcn = createCallbackFcn(app, @PrevioustabButtonPushed, true);
            app.PrevioustabButton.Visible = 'off';
            app.PrevioustabButton.Text = 'Previous tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createmenuspeak(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end