classdef SPEAK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        HTML3     matlab.ui.control.HTML
    end

    
    properties (Access = private)
        DialogApp % Description
    end
    
    
    properties (Access = public)
        ConfigParams;
        % Description        
    end

    
    methods (Access = public)
         function reset(app)
            app.HTML3.Visible = 'on';
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: HTML3
        function HTML3DataChanged(app, event)
            data = app.HTML3.Data;
            app.HTML3.Visible = 'off';
            
            switch data
                case 1
                    %give all default values
                    app.ConfigParams.Audapter.AudioDriverName = 'DriverNotSelected';
                    app.ConfigParams.Experiment.PertType = 'Formant shift';
                    app.ConfigParams.Experiment.PertUnit = 'Hz';
                    app.ConfigParams.Experiment.F1Shift = 0;
                    app.ConfigParams.Experiment.F2Shift = 0;
                    app.ConfigParams.Experiment.BaselineNumBlocks = 0;
                    app.ConfigParams.Experiment.RampNumBlocks = 0;
                    app.ConfigParams.Experiment.HoldNumBlocks = 0;
                    app.ConfigParams.Experiment.WashoutNumBlocks = 0;
                    app.ConfigParams.Game.PicPath = ['Directory has not been selected'];
                    app.DialogApp = createmenuspeak(app);
                case 2
                    [file,path] = uigetfile('*.mat','Select a config file');
                    if isequal(file,0) || isequal(path,0)
                        reset(app);
                    else
                        app.DialogApp = loadstartspeak(app,path,file);
                    end
                case 3
                    delete(app.DialogApp)
                    delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HTML3 = uihtml(app.UIFigure);
            app.HTML3.HTMLSource = 'speak-source/p3_com_title_animation.html';
            app.HTML3.DataChangedFcn = createCallbackFcn(app, @HTML3DataChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'MaxLabLogo_SpeechResearch.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPEAK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end